classdef app_for_pendulum < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        SetPID            matlab.ui.control.Button
        PEditFieldLabel   matlab.ui.control.Label
        PEditField        matlab.ui.control.NumericEditField
        PIDSetting        matlab.ui.control.Label
        IEditFieldLabel   matlab.ui.control.Label
        IEditField        matlab.ui.control.NumericEditField
        DEditFieldLabel   matlab.ui.control.Label
        DEditField        matlab.ui.control.NumericEditField
        Settings          matlab.ui.control.Label
        ALabel            matlab.ui.control.Label
        AEditField        matlab.ui.control.NumericEditField
        TLabel            matlab.ui.control.Label
        TEditField        matlab.ui.control.NumericEditField
        SettisgSin        matlab.ui.control.Button
        Pause             matlab.ui.control.Button
        sinAxTButton      matlab.ui.control.Button
        Start             matlab.ui.control.Button
        by_FORVARD        matlab.ui.control.Image
        ProgrammName      matlab.ui.control.Label
        CheckBox          matlab.ui.control.CheckBox
        COMDropDownLabel  matlab.ui.control.Label
        COMDropDown       matlab.ui.control.DropDown
        AxesInputIncoder  matlab.ui.control.UIAxes
        AxesOutput        matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        isPaused logical = false % Description
        isSinactived logical = false  
        %Timer

    end
    
    properties (Access = private)
        Timer % Description инициализируем таймер 
        tDatareal double = [0]; % Хранит значения времени
        ADatareal double = [0]; % Хранит значения углов
        tDatasinzad double = [0]; % Хранит значения времени
        ADatasynzad double = [0]; % Хранит значения углов
        AMP uint8 = 0;
        c double = 0;
        T uint16 = 0.0000001;
        P uint16 = 0;
        I uint16 = 0;
        D uint16 = 0;
        valueSIN double = 0;
        timework double = 0;
        sinA double = [0 0];
        diskretTime  double = 0.02;
        t double = [ 0];
        data double = [0];
        u_datareal double = [0 0];
        error_datareal double = [0 0];
        comPortName = ' ';
        baudRate = 115200;
        PacketStartByte uint8 = 0x88; % преамбула
        PacketType uint8 = 0x00;         % тип команды
        DataLength uint8 = 0;            % длина данных
        DataBytes uint8 = [0, 0];        % данные (например, Kp_HI, Kp_LO)
        CrcReceived uint8 = 0;          % полученная CRC
        CrcCalculated uint8 = 0;        % вычисленная CRC
        % === Буферы ===
        rxBuffer uint8 = [];            % буфер принятых байтов
        txBuffer uint8 = [];            % буфер отправленных байтов
        s ;
    end
    
    methods (Access = private)
        function figureupdate(app, ~, ~)
            if ~isvalid(app.s) || isempty(instrfind('Port', app.comPortName))
                stop(app.Timer);
                error('Порт COM закрыт или удален.');
            end
            app.t = [app.t(1)+app.diskretTime app.t(2)+app.diskretTime];
            %stop(app.WindSpeedTimer);
            if ~app.isPaused %&& ishandle(app.AxesInputIncoder) 
            % Чтение данных из COM-порта
                app.data = fread(app.s, 11); % Читаем 11 байт
                if ~isempty(app.data)
                    if app.data(1)==0x1C
                        int_value = typecast(uint8(flipud(app.data(2:3))), 'int16'); % Преобразуем данные
                        value = int_value / 1.39;
                        % Обновление данных графика
                        app.tDatareal = [app.tDatareal, length(app.tDatareal) * 0.016];
                        app.ADatareal = [app.ADatareal(2), value];
                        %app.xData = [app.xData(2) app.c+1]%[app.xData, length(app.xData) * 0.016384];
                        %app.yData = [app.timework app.timework+app.diskretTime]%[app.yData, value];
                        % Ограничение размера данных (например, последние 100 точек)
                        % if length(app.xData) > 100
                        %   app.xData = app.xData(2:end);
                        %   app.yData = app.yData(2:end);
                        % end
                        plot(app.AxesInputIncoder, app.t, app.ADatareal, 'blue');
                        
                        % Обновление графика
                        %cla(app.AxesInputIncoder);
                        %cla(app.AxesInputIncoder);
                    end
                    if app.data(4)==0x2D
                        int_value = typecast(uint8(flipud(app.data(5:6))), 'uint16'); % Преобразуем данные;
                        app.u_datareal = [app.u_datareal(2) int_value];
                        plot(app.AxesOutput, app.t, app.u_datareal/65535, 'black');
                        
                    end
                    if app.data(7)==0x2C
                        int_value = typecast(uint8(flipud(app.data(8:11))), 'int32'); % Преобразуем данные;
                        app.error_datareal = [app.error_datareal(2), int_value];
                        plot(app.AxesOutput, app.t, app.error_datareal*2/65535, 'red');
                    end
                
                else 
                    
                    app.tDatareal = [0 0];
                    app.ADatareal = [0 0];    
                    plot(app.AxesOutput, app.t, app.u_datareal, 'black');
                    plot(app.AxesInputIncoder, app.t, app.ADatareal, 'blue');
                    %app.t = [app.t(1)+app.diskretTime,app.t(2)+app.diskretTime];
                end
                
                    %app.timework = app.timework + app.diskretTime;
  
                    if app.isSinactived 
                        app.valueSIN = double(app.AMP)*sin(app.t/double(app.T/1000));
                        app.sinA = [ app.valueSIN];
                        %cla(app.AxesInputIncoder);
                        
                        plot(app.AxesInputIncoder, app.t, app.sinA, 'r');
                        %drawnow
                    end

                    
            end
        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SettisgSin
        function SettisgSinPushed(app, event)

                app.AMP = app.AEditField.Value;
                app.T = app.TEditField.Value;
                fwrite(app.s, 0x0A);
                pause(0.05);
                fwrite(app.s, app.AMP);
                pause(0.05);
                fwrite(app.s, 0x0B);
                pause(0.05);
                fwrite(app.s, uint8(bitshift(app.T,8) & 0xFF));
                pause(0.05);
                fwrite(app.s, uint8(app.T & 0xFF));

        end

        % Button down function: AxesInputIncoder
        function AxesInputIncoderButtonDown(app, event)
                
        end

        % Button pushed function: Pause
        function PauseButtonPushed(app, event)
            app.isPaused = ~app.isPaused;
            if app.isPaused
                stop(app.Timer);
                fwrite(app.s, 0x0A);
                app.Pause.Text = 'Возобновить работу';
            else
                start(app.Timer);
                fwrite(app.s, 0x3C);
                app.Pause.Text = 'Приостановить работу';
            end
        end

        % Image clicked function: by_FORVARD
        function by_FORVARDImageClicked(app, event)
                url = 'https://github.com/XFORVARDX/pendulum_control_system';
                %    Открытие URL в браузере
                web(url, '-browser');
        end

        % Button pushed function: Start
        function StartButtonPushed(app, event)
            
            %[verStr, relStr] = version;
            if verLessThan('matlab', '9.10')
            % Для R2020b и ниже
                % Проверка доступности COM-порта
                if ~isempty(instrfind('Port', app.comPortName))
                    %delete(instrfind);
                end
                try 
                    app.s = serial(app.comPortName, ...
                    'BaudRate', app.baudRate, ...
                    'Parity', 'none', ...
                    'DataBits', 8, ...
                    'StopBits', 1, ...
                    'Timeout', 10);
                fopen(app.s);
                catch ME
                    uialert(app.UIFigure, ['Ошибка открытия порта: ', ME.message], 'COM-порт');
                    return;
                end
                else
                % Для R2021a и выше
                if ~isempty(instrfind('Port', app.comPortName))
                    delete(instrfind);
                end
                try
                    app.s = serialport(app.comPortName, app.baudRate);
                catch ME
                    uialert(app.UIFigure, ['Ошибка создания порта: ', ME.message], 'COM-порт');
                    return;
                end
              end
%                     %error('COM порт уже занят другим процессом.');
%             else
%             % Инициализация объекта serial
%             try
%                 app.s = serialport(app.comPortName,app.baudRate); 
%             %set(app.s, 'BaudRate', 38400, 'Timeout', 10, 'Terminator', '');
%             % Открытие порта
%             
%                 fopen(app.s);
%             catch ME
%                 error(['Не удалось открыть порт COM6: ', ME.message]);
%             end
%             end

            % Инициализация таймера
            app.ADatareal = [0 0];
            app.tDatareal = [0 0];
            app.ADatasynzad = [0 0];
            app.tDatasinzad =[0 0];
            app.Timer = timer('ExecutionMode', 'fixedRate', ...
                              'Period', app.diskretTime, ... 
                              'TimerFcn', @(src, event) figureupdate(app,  src, event));
            app.t = [0 app.diskretTime];
            start(app.Timer);
            app.Start.Text = 'Маятник запущен';
        end

        % Value changed function: AEditField
        function AEditFieldValueChanged(app, event)
            app.AMP = app.AEditField.Value;
        end

        % Value changed function: TEditField
        function TEditFieldValueChanged(app, event)
            app.T = app.TEditField.Value;
            
        end

        % Button pushed function: sinAxTButton
        function sinAxTButtonPushed(app, event)
            app.isSinactived = true;
            

        end

        % Button pushed function: SetPID
        function SetPIDButtonPushed(app, event)
            % if ~isempty(instrfind('Port', app.comPortName))
            %     app.s = serialport(app.comPortName, app.baudRate);
            % end
                write(app.s, [app.PacketStartByte1, app.PacketStartByte2, 0x01, 4, ...
                typecast(int16(30), 'uint8'), typecast(int16(24), 'uint8')]);
                fwrite(app.s, 0x1A);
                pause(0.05);
                fwrite(app.s, app.P);
                fwrite(app.s, uint8(bitshift(app.P,8) & 0xFF));
                pause(0.05);
                fwrite(app.s, uint8(app.P & 0xFF));
                pause(0.05);
                fwrite(app.s, 0x1B);
                pause(0.05);
                fwrite(app.s, uint8(bitshift(app.I,8) & 0xFF));
                pause(0.05);
                fwrite(app.s, uint8( app.I & 0xFF));
                fwrite(app.s, 0x1F);
                pause(0.05);
                fwrite(app.s, uint8( bitshift(app.D,8) & 0xFF));
                pause(0.05);
                fwrite(app.s, uint8( app.D & 0xFF));

        end

        % Value changed function: COMDropDown
        function COMDropDownValueChanged(app, event)
            app.comPortName = app.COMDropDown.Value;
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.SetPID = uibutton(app.UIFigure, 'push');
            app.SetPID.ButtonPushedFcn = createCallbackFcn(app, @SetPIDButtonPushed, true);
            app.SetPID.Text = 'Отправить настройки';
            app.PEditFieldLabel = uilabel(app.UIFigure);
            app.PEditFieldLabel.Text = 'P';
            app.PEditField = uieditfield(app.UIFigure, 'numeric');
            app.PEditField.Limits = [0 1000];
            app.PIDSetting = uilabel(app.UIFigure);
            app.PIDSetting.Text = 'Коэффициенты PID';
            app.IEditFieldLabel = uilabel(app.UIFigure);
            app.IEditFieldLabel.Text = 'I';
            app.IEditField = uieditfield(app.UIFigure, 'numeric');
            app.IEditField.Limits = [0 1000];
            app.DEditFieldLabel = uilabel(app.UIFigure);
            app.DEditFieldLabel.Text = 'D';
            app.DEditField = uieditfield(app.UIFigure, 'numeric');
            app.DEditField.Limits = [0 1000];
            app.Settings = uilabel(app.UIFigure);
            app.Settings.Text = 'Настройки колебаний';
            app.ALabel = uilabel(app.UIFigure);
            app.ALabel.Text = 'A,град';
            app.AEditField = uieditfield(app.UIFigure, 'numeric');
            app.AEditField.Limits = [0 90];
            app.AEditField.ValueChangedFcn = createCallbackFcn(app, @AEditFieldValueChanged, true);
            app.AEditField.Value = 30;
            app.TLabel = uilabel(app.UIFigure);
            app.TLabel.Text = 'T,мГц';
            app.TEditField = uieditfield(app.UIFigure, 'numeric');
            app.TEditField.Limits = [100 3000];
            app.TEditField.ValueChangedFcn = createCallbackFcn(app, @TEditFieldValueChanged, true);
            app.TEditField.Value = 1000;
            app.SettisgSin = uibutton(app.UIFigure, 'push');
            app.SettisgSin.ButtonPushedFcn = createCallbackFcn(app, @SettisgSinPushed, true);
            app.SettisgSin.Text = 'Отправить настройки колебаний';
            app.Pause = uibutton(app.UIFigure, 'push');
            app.Pause.ButtonPushedFcn = createCallbackFcn(app, @PauseButtonPushed, true);
            app.Pause.Text = 'Приостановить работу';
            app.sinAxTButton = uibutton(app.UIFigure, 'push');
            app.sinAxTButton.ButtonPushedFcn = createCallbackFcn(app, @sinAxTButtonPushed, true);
            app.sinAxTButton.Text = 'Построить sinA(x/T)';
            app.Start = uibutton(app.UIFigure, 'push');
            app.Start.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.Start.Text = 'Запустить маятник';
            app.by_FORVARD = uiimage(app.UIFigure);
            app.by_FORVARD.ImageClickedFcn = createCallbackFcn(app, @by_FORVARDImageClicked, true);
            app.by_FORVARD.ImageSource = 'C:\study\diplom\app\cjh1yt1xkjmrnw1r.png';
            app.ProgrammName = uilabel(app.UIFigure);
            app.ProgrammName.Text = 'Управление маятником';
            app.CheckBox = uicheckbox(app.UIFigure);
            app.CheckBox.Text = 'Режим разраба';
            app.COMDropDownLabel = uilabel(app.UIFigure);
            app.COMDropDownLabel.Text = 'COM';
            app.COMDropDown = uidropdown(app.UIFigure);
            app.COMDropDown.Items = {'COM1', 'COM2', 'COM3', 'COM4', 'COM5', 'COM6', 'COM7', 'COM8', 'COM9', 'COM10', 'COM11', 'COM12', 'COM13'};
            app.COMDropDown.ValueChangedFcn = createCallbackFcn(app, @COMDropDownValueChanged, true);
            app.COMDropDown.Value = 'COM1';
            app.AxesInputIncoder = uiaxes(app.UIFigure);
            title(app.AxesInputIncoder, 'график угла отклонения маятника')
            xlabel(app.AxesInputIncoder, 't,c')
            ylabel(app.AxesInputIncoder, 'A,град')
            zlabel(app.AxesInputIncoder, 'Z')
            app.AxesInputIncoder.ButtonDownFcn = createCallbackFcn(app, @AxesInputIncoderButtonDown, true);
            app.AxesOutput = uiaxes(app.UIFigure);
            title(app.AxesOutput, 'График управления')
            xlabel(app.AxesOutput, 't,с')
            ylabel(app.AxesOutput, 'U,В')
            zlabel(app.AxesOutput, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_for_pendulum

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end